classdef interfejs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PunktystatusquoPanel         matlab.ui.container.Panel
        UITable2_2                   matlab.ui.control.Table
        PozostaepunktyPanel          matlab.ui.container.Panel
        UITable2_3                   matlab.ui.control.Table
        PunktyAspiracjiIdealnePanel  matlab.ui.container.Panel
        UITable2                     matlab.ui.control.Table
        WynikiPanel                  matlab.ui.container.Panel
        UITable_Wyniki               matlab.ui.control.Table
        AkcjePanel                   matlab.ui.container.Panel
        RozwiButton                  matlab.ui.control.Button
        AlgorytmDropDown             matlab.ui.control.DropDown
        AlgorytmDropDownLabel        matlab.ui.control.Label
        WartociPanel                 matlab.ui.container.Panel
        UsuButton_2                  matlab.ui.control.Button
        DodajButton_2                matlab.ui.control.Button
        UITable_2                    matlab.ui.control.Table
        KryteriaPanel                matlab.ui.container.Panel
        ZatwierdButton               matlab.ui.control.Button
        UsuButton                    matlab.ui.control.Button
        DodajButton                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        GeneracjaPanel               matlab.ui.container.Panel
        gen_DLabel                   matlab.ui.control.Label
        gen_CLabel                   matlab.ui.control.Label
        gen_BLabel                   matlab.ui.control.Label
        gen_ALabel                   matlab.ui.control.Label
        gen_wybrKolKrytDropDown      matlab.ui.control.DropDown
        jakiekryteriaDropDownLabel   matlab.ui.control.Label
        gen_WybrKolKrytSpinner       matlab.ui.control.Spinner
        WybrkryteriumSpinnerLabel    matlab.ui.control.Label
        gen_DEditField               matlab.ui.control.NumericEditField
        gen_GenerujDaneButton        matlab.ui.control.Button
        gen_CEditField               matlab.ui.control.NumericEditField
        gen_BEditField               matlab.ui.control.NumericEditField
        gen_AEditField               matlab.ui.control.NumericEditField
        gen_RozkadDropDown           matlab.ui.control.DropDown
        RozkadDropDownLabel          matlab.ui.control.Label
        gen_ilePktEditField          matlab.ui.control.NumericEditField
        ilepunktwLabel               matlab.ui.control.Label
    end

     properties (Access = private)
        PrivateZapisaneDane % Zmienna do przechowywania zapisanych danych
        PrivateA1
        PrivateA2
        PrivateWybor
        Kryteria % Zmienna do przychowywania nazw i typów kryteriów 
     end
    
    methods (Access = private)
        function dane_with_lp = add_lp(~,dane,tabela)
            % Dodanie kolumny Lp.
            lp_column = (1:size(dane,1))'; % Generuje kolumnę "Lp." z wartościami od 1 do liczby wierszy
            dane_with_lp = [lp_column, dane]; % Łączenie kolumny "Lp." z istniejącymi danymi
            % Generowanie nazw kolumn
            num_columns = size(dane_with_lp, 2);
            column_names = ["Lp.", arrayfun(@(x) sprintf("Kryterium %d", x), 1:(num_columns-1), 'UniformOutput', false)];
        
            % Aktualizacja tabeli w interfejsie użytkownika
            tabela.Data = dane_with_lp;
            tabela.ColumnName = column_names; % Ustawienie nazw kolumn
        end
        function dane = generator_danych(app,rodzaj,ilPkt,ilKryt,A,B,C,D)
            dane = zeros(ilPkt,ilKryt);
            switch(rodzaj)
                case "Gauss"
                    mu = A;
                    sigma = B;
                    gm = gmdistribution(mu,sigma);
                    for i = 1:ilKryt
                        dane(:,i) = random(gm, ilPkt);
                    end                  
                case "Eksponencjalny"
                    mu = A;
                    for i = 1:ilKryt
                        for j = 1:ilPkt
                            dane(j,i) = random('Exponential',mu);
                        end
                    end   

                case "Poissona"
                    mu = A;
                    for i = 1:ilKryt
                        for j = 1:ilPkt
                            dane(j,i) = random('Poisson',mu);
                        end
                    end   

                case "Gamma"
                    alpha = A;
                    beta = B;
                    for i = 1:ilKryt
                        for j = 1:ilPkt
                            dane(j,i) = random('Gamma',alpha,beta);
                        end
                    end  
            end
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DodajButton
        function DodajKryteriumButtonPushed(app, event)
            % Pobierz aktualne dane z tabeli
            currentData = app.UITable.Data;
        
            % Sprawdź liczbę kolumn
            if isempty(currentData)
                numCols = 4; % 3 kolumny + 1 nowa kolumna dla wag
            else
                numCols = size(currentData, 2);
            end
        
            % Ustal ostatni numer w nazwie kryterium
            if isempty(currentData)
                lastNumber = 0; % Jeśli tabela jest pusta, zacznij od 0
            else
                % Wydobądź numery z kolumny "Nazwa kryterium"
                kryteriumNames = currentData(:, 2);
                numbers = cellfun(@(x) sscanf(x, 'Kryterium %d'), kryteriumNames, 'UniformOutput', false);
                numbers = cell2mat(numbers(~cellfun('isempty', numbers))); % Usuń puste wartości
                lastNumber = max(numbers); % Największy istniejący numer
            end
        
            % Tworzymy nowy pusty wiersz
            newRow = cell(1, numCols);
        
            % Dodajemy domyślne wartości
            newRow{1} = size(currentData, 1) + 1; % Numer wiersza (Lp.)
            newRow{2} = ['Kryterium ', num2str(lastNumber + 1)]; % Nazwa kryterium
            newRow{3} = 'Min'; % Domyślny kierunek
            newRow{4} = 0; % Domyślna waga ustawiona na 0
        
            % Dodaj nowy wiersz do danych
            updatedData = [currentData; newRow];
        
            % Zaktualizuj dane tabeli
            app.UITable.Data = updatedData;
        
            % Skonfiguruj format kolumn tabeli, aby umożliwić modyfikację wag
            app.UITable.ColumnFormat = {[] [] {'Min', 'Max'} []}; 
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: DodajButton_2
        function DodajButton_2Pushed(app, event)
                % Pobierz dane z UITable_2
            daneTabela = app.UITable_2.Data(:, 2:end);
        
            % Sprawdzenie, czy tabela jest pusta
            if isempty(daneTabela)
                % Wyświetl komunikat o pustej tabeli
                uialert(app.UIFigure, 'Tabela jest pusta. Nie można zapisać danych.', 'Błąd');
                return;
            end
        
            % Zapis danych do pola prywatnego
            try
                app.PrivateZapisaneDane = daneTabela; % Zapisanie danych do pola prywatnego
                % Wyświetlenie komunikatu o powodzeniu
                uialert(app.UIFigure, 'Dane zostały pomyślnie zapisane.', 'Sukces');
                disp('Zapisane dane:');
                disp(app.PrivateZapisaneDane);
                typKryteriow = zeros(1, size(app.UITable.Data, 1)); 
               
                % Przejście przez wszystkie wiersze tabeli
                for i = 1:size(app.UITable.Data, 1)
                    % Sprawdzanie typu kryterium ('Max' -> 1, 'Min' -> -1)
                    if strcmp(app.UITable.Data{i, 3}, 'Max')
                        typKryteriow(i) = 1; % Max -> 1
                    else
                        typKryteriow(i) = -1; % Min -> -1
                    end
                
                end

                [A1,A2,res] = rozdziel_punkty(app.PrivateZapisaneDane,typKryteriow);
                app.PrivateA1 = A1;
                app.PrivateA2 = A2;
                app.PrivateWybor = res;

                add_lp(app,A1,app.UITable2);
                add_lp(app,A2,app.UITable2_2);
                add_lp(app,res,app.UITable2_3);

                disp("A1:")
                disp(A1)

                disp("A2:")
                disp(A2)
                
                disp("Punkty Do wyboru:")
                disp(res)
                
            catch ME
                % Obsługa błędu i wyświetlenie komunikatu
                uialert(app.UIFigure, sprintf('Wystąpił błąd podczas zapisywania danych:\n%s', ME.message), 'Błąd');
            end
        end

        % Button pushed function: UsuButton
        function UsunButton(app, event)
            % Sprawdzenie, czy tabela ma dane
            if isempty(app.UITable.Data)
                uialert(app.UIFigure, 'Tabela jest pusta.', 'Błąd');
                return;
            end
        
            % Pobranie zaznaczenia w tabeli
            selectedRow = app.UITable.Selection;
        
            % Sprawdzenie, czy zaznaczono wiersz
            if isempty(selectedRow)
                uialert(app.UIFigure, 'Nie zaznaczono wiersza.', 'Błąd');
                return;
            end
        
            % Pobranie indeksu wiersza do usunięcia
            rowIndex = selectedRow(1); % Pierwszy element Selection to numer wiersza
        
            % Sprawdzenie poprawności indeksu
            if rowIndex < 1 || rowIndex > size(app.UITable.Data, 1)
                uialert(app.UIFigure, 'Nieprawidłowy indeks wiersza.', 'Błąd');
                return;
            end
        
            % Pobranie aktualnych danych z tabeli
            currentData = app.UITable.Data;
        
            % Usunięcie wybranego wiersza
            updatedData = currentData;
            updatedData(rowIndex, :) = []; % Usuń wiersz
        
            % Zaktualizowanie danych w UITable
            app.UITable.Data = updatedData;
        
            % Opcjonalne odświeżenie numerów w kolumnie "Lp."
            for i = 1:size(updatedData, 1)
                updatedData{i, 1} = i; % Aktualizacja kolumny "Lp."
            end
            app.UITable.Data = updatedData;
        end

        % Value changed function: gen_RozkadDropDown
        function gen_WyborRozkladu(app, event)
            value = app.gen_RozkadDropDown.Value;
            switch(value)
                case "Gauss"
                    app.gen_ALabel.Text = "średnia";

                    app.gen_BEditField.Visible = "on";
                    app.gen_BLabel.Visible = "on";
                    app.gen_BLabel.Text = "wariancja";

                    app.gen_CEditField.Visible = "off";
                    app.gen_CLabel.Visible = "off";

                    app.gen_DEditField.Visible = "off";
                    app.gen_DLabel.Visible = "off";
                case "Eksponencjalny"
                    app.gen_ALabel.Text = "średnia";

                    app.gen_BEditField.Visible = "off";
                    app.gen_BLabel.Visible = "off";

                    app.gen_CEditField.Visible = "off";
                    app.gen_CLabel.Visible = "off";

                    app.gen_DEditField.Visible = "off";
                    app.gen_DLabel.Visible = "off";
                case "Poissona"
                    app.gen_ALabel.Text = "średnia";

                    app.gen_BEditField.Visible = "off";
                    app.gen_BLabel.Visible = "off";

                    app.gen_CEditField.Visible = "off";
                    app.gen_CLabel.Visible = "off";

                    app.gen_DEditField.Visible = "off";
                    app.gen_DLabel.Visible = "off";
                case "Gamma"
                    app.gen_ALabel.Text = "kształt";

                    app.gen_BEditField.Visible = "on";
                    app.gen_BLabel.Visible = "on";
                    app.gen_BLabel.Text = "skala";

                    app.gen_CEditField.Visible = "off";
                    app.gen_CLabel.Visible = "off";

                    app.gen_DEditField.Visible = "off";
                    app.gen_DLabel.Visible = "off";
            end
        end

        % Value changed function: gen_wybrKolKrytDropDown
        function gen_wyborIleWypelnicKryteriow(app, event)
            value = app.gen_wybrKolKrytDropDown.Value;
            switch(value)
                case "wszystkie"
                    app.gen_WybrKolKrytSpinner.Enable = "off";
                case "pojedyncze"
                    app.gen_WybrKolKrytSpinner.Enable = "on";
            end
        end

        % Button pushed function: gen_GenerujDaneButton
        function gen_GenerujDaneButtonPushed(app, event)
            app.UITable_2.Data = []; % Czyszczenie danych w tabeli
            app.UITable_2.ColumnName = {}; % Czyszczenie nazw kolumn
            if app.gen_wybrKolKrytDropDown.Value == "wszystkie"
                KrytBeg = 1;
                KrytEnd = size(app.UITable.Data, 1);
            else
                KrytBeg = app.gen_WybrKolKrytSpinner.Value;
                KrytEnd = KrytBeg;
            end
        
            A = app.gen_AEditField.Value;
            B = app.gen_BEditField.Value;
            C = app.gen_CEditField.Value;
            D = app.gen_DEditField.Value;
            ilePkt = app.gen_ilePktEditField.Value;
            rozklad = app.gen_RozkadDropDown.Value;
        
            new_dane = generator_danych(app, rozklad, ilePkt, KrytEnd - KrytBeg + 1, A, B, C, D);
            % Pobierz aktualne dane z tabeli
            tab_ilePkt = size(app.UITable_2.Data, 1);
            tab_ileKry = size(app.UITable.Data, 1);
            
            if tab_ilePkt < ilePkt
                dane = zeros(ilePkt, tab_ileKry);
                dane(1:tab_ilePkt, :) = app.UITable_2.Data;
            else
                dane = app.UITable_2.Data;
            end
        
            % Dodanie nowych danych
            dane(1:ilePkt, KrytBeg:KrytEnd) = new_dane;
        
            % Pobierz kierunki kryteriów z tabeli app.UITable
            kierunkiKryteriow = app.UITable.Data(:, 3); % Kolumna 3 to "Kierunek" z wartościami 'Min' lub 'Max'
        
            % Dodanie kolumny Lp.
            lp_column = (1:ilePkt)'; % Generuje kolumnę "Lp." z wartościami od 1 do liczby wierszy
            dane_with_lp = [lp_column, dane]; % Łączenie kolumny "Lp." z istniejącymi danymi
            % Generowanie nazw kolumn
            num_columns = size(dane_with_lp, 2);
            column_names = ["Lp.", arrayfun(@(x) sprintf("Kryterium %d", x), 1:(num_columns-1), 'UniformOutput', false)];
        
            % Aktualizacja tabeli w interfejsie użytkownika
            app.UITable_2.Data = dane_with_lp;
            app.UITable_2.ColumnName = column_names; % Ustawienie nazw kolumn
        end

        % Button pushed function: UsuButton_2
        function UsunButton2(app, event)
        % Sprawdzenie, czy tabela ma dane
            if isempty(app.UITable_2.Data)
                uialert(app.UIFigure, 'Tabela jest pusta.', 'Błąd');
                return;
            end
        
            % Pobranie zaznaczenia w tabeli
            selectedCell = app.UITable_2.Selection;
        
            % Sprawdzenie, czy zaznaczono komórkę
            if isempty(selectedCell)
                uialert(app.UIFigure, 'Nie zaznaczono żadnej komórki.', 'Błąd');
                return;
            end
        
            % Pobranie indeksu kolumny do usunięcia
            colIndex = selectedCell(1, 2); % Drugi element Selection to numer kolumny
        
            % Sprawdzenie poprawności indeksu kolumny
            if colIndex < 2 || colIndex > size(app.UITable_2.Data, 2)
                uialert(app.UIFigure, 'Nieprawidłowy indeks kolumny lub zaznaczono "Lp."', 'Błąd');
                return;
            end
        
            % Pobranie aktualnych danych z tabeli
            currentData = app.UITable_2.Data;
        
            % Usunięcie wybranej kolumny
            updatedData = currentData;
            updatedData(:, colIndex) = []; % Usuń kolumnę
        
            % Aktualizacja nazw kolumn
            columnNames = app.UITable_2.ColumnName;
            columnNames(colIndex) = []; % Usuń nazwę wybranej kolumny
        
            % Zaktualizowanie nazw pozostałych kolumn "Kryterium X"
            for i = 2:length(columnNames)
                columnNames{i} = sprintf('Kryterium %d', i - 1);
            end
        
            % Zaktualizowanie danych i nazw kolumn w UITable
            app.UITable_2.Data = updatedData;
            app.UITable_2.ColumnName = columnNames;
        end

        % Button pushed function: RozwiButton
        function RozwiazButtonPushed(app, event)
            % Inicjalizacja wektorów
            typKryteriow = zeros(1, size(app.UITable.Data, 1)); % Wektor -1 (Min) lub 1 (Max)
            wagiKryteriow = zeros(1, size(app.UITable.Data, 1)); % Wektor wag
            
            % Przejście przez wszystkie wiersze tabeli
            for i = 1:size(app.UITable.Data, 1)
                % Sprawdzanie typu kryterium ('Max' -> 1, 'Min' -> -1)
                if strcmp(app.UITable.Data{i, 3}, 'Max')
                    typKryteriow(i) = 1; % Max -> 1
                else
                    typKryteriow(i) = -1; % Min -> -1
                end
            
                % Pobieranie wagi kryterium z czwartej kolumny tabeli
                wagiKryteriow(i) = app.UITable.Data{i, 4};
            end
            
            % Wektor wag i typów można teraz używać w dalszych obliczeniach
            disp('Typy kryteriów (-1 dla Min, 1 dla Max):');
            disp(typKryteriow);
            disp('Wagi kryteriów:');
            disp(wagiKryteriow);
            
            dane = app.PrivateZapisaneDane;
            kryteria = app.Kryteria; % Kryteria z GUI
            wyborAlgorytmu = app.AlgorytmDropDown.Value;
            disp("DANE:")
            disp(dane)
            switch wyborAlgorytmu
                case 'Naiwny z filtracją'
                    wynik = -typKryteriow.*OWD_z_filtrem(-typKryteriow.*dane);
                case 'Naiwny z sortowaniem'
                    wynik = -typKryteriow.*OWD_z_sortowaniem(-typKryteriow.*dane,1);
                case 'Kung-Luccio-Preparata'
                    wynik = typKryteriow.*klp_full(typKryteriow.*dane);
                otherwise
                    uialert(app.UIFigure, 'Nie wybrano algorytmu!', 'Błąd');
                    return;
            end
            disp("WYNIK")
            disp(wynik);
            
            res = licz_topsis(wynik,typKryteriow,wagiKryteriow);
            disp(res)

            app.UITable_Wyniki.Data = {};
            numKryteria = size(wynik, 2); % Liczba kryteriów
            columnHeaders = cell(1, numKryteria+1);
            for i = 1:numKryteria
                columnHeaders{i} = ['Kryterium ', num2str(i)];
            end
            columnHeaders{end} = "Ranking";
            app.UITable_Wyniki.ColumnName = columnHeaders; % Ustawienie nazw kolumn
            
            % Przekazanie wyników do tabeli
            joined_data = [wynik,res(:,2)];
            joined_data = sortrows(joined_data,size(joined_data,2),"descend");
            app.UITable_Wyniki.Data = num2cell(joined_data); % Konwersja macierzy wyników na komórki

    
            figure
            
            if size(wynik,2) == 2
                hold on
                plot(dane(:,1),dane(:,2),'r.')
                plot(wynik(:,1),wynik(:,2),'bo')
                [~,best_idx] = max(res(:,2));
                plot(wynik(best_idx,1),wynik(best_idx,2),'g*')
                legend("Wszystkie Punkty","Punkty Niezdominowane", "Punkt najlepszy według Topsis","Location",'best');
            elseif size(wynik,2) >= 3
                scatter3(dane(:,1),dane(:,2),dane(:,3),'r.')
                hold on
                scatter3(wynik(:,1),wynik(:,2),wynik(:,3),'bo')
                [~,best_idx] = max(res(:,2));
                scatter3(wynik(best_idx,1),wynik(best_idx,2),wynik(best_idx,3),'g*')
                legend("Wszystkie Punkty","Punkty Niezdominowane", "Punkt najlepszy według Topsis","Location",'best');
            end
        end

        % Button pushed function: ZatwierdButton
        function ZatwierdzKrytButton(app, event)
            % Pobranie nazwy kryterium z pola tekstowego
            nazwaKryterium = app.UITable.Data(:, 2);
        
            % Pobranie typu kryterium z listy rozwijanej
            typKryterium = app.UITable.Data(:, 3); % 'min' lub 'max'

             % Pobranie wagi kryterium z pola tekstowego
            wagaKryterium = app.UITable.Data(:, 4); 
        
            % Tworzenie nowego kryterium jako struktura
            noweKryterium.Nazwa = nazwaKryterium;
            noweKryterium.Typ = typKryterium;
            noweKryterium.Waga = wagaKryterium;
        
            % Dodanie kryterium do zmiennej app.Kryteria
            if isempty(app.Kryteria)
                app.Kryteria = struct('Nazwa', {}, 'Typ', {}, 'Waga', {});
            end

            % Dodanie nowego kryterium do istniejącej tablicy
            app.Kryteria(end + 1) = noweKryterium;
            
        
            % Potwierdzenie zapisu
            uialert(app.UIFigure, 'Kryteria zostały zapisane.', 'Sukces');
            disp(app.Kryteria)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneracjaPanel = uipanel(app.UIFigure);
            app.GeneracjaPanel.Title = 'Generacja';
            app.ilepunktwLabel = uilabel(app.GeneracjaPanel);
            app.ilepunktwLabel.Text = 'Liczba punktów';
            app.gen_ilePktEditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.gen_ilePktEditField.Limits = [1 Inf];
            app.gen_ilePktEditField.Value = 1;
            app.RozkadDropDownLabel = uilabel(app.GeneracjaPanel);
            app.RozkadDropDownLabel.Text = 'Rozkład';
            app.gen_RozkadDropDown = uidropdown(app.GeneracjaPanel);
            app.gen_RozkadDropDown.Items = {'Gauss', 'Eksponencjalny', 'Poissona', 'Gamma'};
            app.gen_RozkadDropDown.ValueChangedFcn = createCallbackFcn(app, @gen_WyborRozkladu, true);
            app.gen_RozkadDropDown.Value = 'Gauss';
            app.gen_AEditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.gen_BEditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.gen_CEditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.gen_CEditField.Visible = 'off';
            app.gen_GenerujDaneButton = uibutton(app.GeneracjaPanel, 'push');
            app.gen_GenerujDaneButton.ButtonPushedFcn = createCallbackFcn(app, @gen_GenerujDaneButtonPushed, true);
            app.gen_GenerujDaneButton.Text = 'Generuj dane';
            app.gen_DEditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.gen_DEditField.Visible = 'off';
            app.WybrkryteriumSpinnerLabel = uilabel(app.GeneracjaPanel);
            app.WybrkryteriumSpinnerLabel.Text = 'Wybór kryterium';
            app.gen_WybrKolKrytSpinner = uispinner(app.GeneracjaPanel);
            app.gen_WybrKolKrytSpinner.Limits = [1 Inf];
            app.gen_WybrKolKrytSpinner.Enable = 'off';
            app.gen_WybrKolKrytSpinner.Value = 1;
            app.jakiekryteriaDropDownLabel = uilabel(app.GeneracjaPanel);
            app.jakiekryteriaDropDownLabel.Text = 'jakie kryteria';
            app.gen_wybrKolKrytDropDown = uidropdown(app.GeneracjaPanel);
            app.gen_wybrKolKrytDropDown.Items = {'wszystkie', 'pojedyncze'};
            app.gen_wybrKolKrytDropDown.ValueChangedFcn = createCallbackFcn(app, @gen_wyborIleWypelnicKryteriow, true);
            app.gen_wybrKolKrytDropDown.Value = 'wszystkie';
            app.gen_ALabel = uilabel(app.GeneracjaPanel);
            app.gen_ALabel.Text = 'średnia';
            app.gen_BLabel = uilabel(app.GeneracjaPanel);
            app.gen_BLabel.Text = 'wariancja';
            app.gen_CLabel = uilabel(app.GeneracjaPanel);
            app.gen_CLabel.Visible = 'off';
            app.gen_CLabel.Text = 'C';
            app.gen_DLabel = uilabel(app.GeneracjaPanel);
            app.gen_DLabel.Visible = 'off';
            app.gen_DLabel.Text = 'D';
            app.KryteriaPanel = uipanel(app.UIFigure);
            app.KryteriaPanel.Title = 'Kryteria';
            app.UITable = uitable(app.KryteriaPanel);
            app.UITable.ColumnName = {'Lp.'; 'Nazwa kryterium'; 'Kierunek'; 'Waga'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.DodajButton = uibutton(app.KryteriaPanel, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajKryteriumButtonPushed, true);
            app.DodajButton.Text = 'Dodaj';
            app.UsuButton = uibutton(app.KryteriaPanel, 'push');
            app.UsuButton.ButtonPushedFcn = createCallbackFcn(app, @UsunButton, true);
            app.UsuButton.Text = 'Usuń';
            app.ZatwierdButton = uibutton(app.KryteriaPanel, 'push');
            app.ZatwierdButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdzKrytButton, true);
            app.ZatwierdButton.Text = 'Zatwierdź';
            app.WartociPanel = uipanel(app.UIFigure);
            app.WartociPanel.Title = 'Wartości';
            app.UITable_2 = uitable(app.WartociPanel);
            app.UITable_2.ColumnName = {'Lp.'};
            app.UITable_2.RowName = {};
            app.DodajButton_2 = uibutton(app.WartociPanel, 'push');
            app.DodajButton_2.ButtonPushedFcn = createCallbackFcn(app, @DodajButton_2Pushed, true);
            app.DodajButton_2.Text = 'Dodaj';
            app.UsuButton_2 = uibutton(app.WartociPanel, 'push');
            app.UsuButton_2.ButtonPushedFcn = createCallbackFcn(app, @UsunButton2, true);
            app.UsuButton_2.Text = 'Usuń';
            app.AkcjePanel = uipanel(app.UIFigure);
            app.AkcjePanel.Title = 'Akcje';
            app.AlgorytmDropDownLabel = uilabel(app.AkcjePanel);
            app.AlgorytmDropDownLabel.Text = 'Algorytm';
            app.AlgorytmDropDown = uidropdown(app.AkcjePanel);
            app.AlgorytmDropDown.Items = {'Naiwny z filtracją', 'Naiwny z sortowaniem', 'Kung-Luccio-Preparata'};
            app.AlgorytmDropDown.Value = 'Naiwny z filtracją';
            app.RozwiButton = uibutton(app.AkcjePanel, 'push');
            app.RozwiButton.ButtonPushedFcn = createCallbackFcn(app, @RozwiazButtonPushed, true);
            app.RozwiButton.Text = 'Rozwiąż';
            app.WynikiPanel = uipanel(app.UIFigure);
            app.WynikiPanel.Title = 'Wyniki';
            app.UITable_Wyniki = uitable(app.WynikiPanel);
            app.UITable_Wyniki.ColumnName = '';
            app.UITable_Wyniki.RowName = {};
            app.PunktyAspiracjiIdealnePanel = uipanel(app.UIFigure);
            app.PunktyAspiracjiIdealnePanel.Title = 'Punkty Aspiracji/Idealne';
            app.UITable2 = uitable(app.PunktyAspiracjiIdealnePanel);
            app.UITable2.ColumnName = {'Lp.'};
            app.UITable2.RowName = {};
            app.PozostaepunktyPanel = uipanel(app.UIFigure);
            app.PozostaepunktyPanel.Title = 'Pozostałe punkty';
            app.UITable2_3 = uitable(app.PozostaepunktyPanel);
            app.UITable2_3.ColumnName = {'Lp.'};
            app.UITable2_3.RowName = {};
            app.PunktystatusquoPanel = uipanel(app.UIFigure);
            app.PunktystatusquoPanel.Title = 'Punkty status-quo';
            app.UITable2_2 = uitable(app.PunktystatusquoPanel);
            app.UITable2_2.ColumnName = {'Lp.'};
            app.UITable2_2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfejs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end